classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadFileMenu                  matlab.ui.container.Menu
        LoadWedgesFileMenu            matlab.ui.container.Menu
        LoadConstraintsFileMenu       matlab.ui.container.Menu
        LoadStrategyFileMenu          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        StrategiesTab                 matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        ElectricityStrategiesListBoxLabel  matlab.ui.control.Label
        ElectricityStrategiesListBox  matlab.ui.control.ListBox
        FuelStrategiesListBoxLabel    matlab.ui.control.Label
        FuelStrategiesListBox         matlab.ui.control.ListBox
        TransportationStrategiesListBoxLabel  matlab.ui.control.Label
        TransportationStrategiesListBox  matlab.ui.control.ListBox
        BiostorageStrategiesListBoxLabel  matlab.ui.control.Label
        BiostorageStrategiesListBox   matlab.ui.control.ListBox
        Tab2                          matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        StartYearTextAreaLabel        matlab.ui.control.Label
        StartYearTextArea             matlab.ui.control.TextArea
        EndYearTextAreaLabel          matlab.ui.control.Label
        EndYearTextArea               matlab.ui.control.TextArea
        EnterastartandendLabel        matlab.ui.control.Label
        yeartoeditthegraphsLabel      matlab.ui.control.Label
        domainLabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        WedgesData      % This property will hold processed data.
        ConstraintsData % This property will hold processed data.
        StrategyData    % This property will hold processed data.
        XDataHist  %Stores historical XData
        YDataHist  %Stores historical YData
    end

    methods (Access = private)
    
        function WedgesData = readWedges(app, fileID)
            % This fucntion's purpose is to read in and process
            % the wedges file and outputs the data as a matrix.
            % In the matrix M(1,1) would correspond to the label of the first wedge
            % M(2,2) would correspind to the value of the second wedge. Etc.
            
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
             
        end
        
        
        function ConstraintsData = readConstraints(app, fileID)
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
        end
        
        
        function StrategyData = readStrategy(app, fileID)
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~  
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            info = tdfread('historicaldata.txt');
            app.XDataHist = info.Year;
            app.YDataHist = info.fossil_fuel_and_industry_GtC0x2Fyr;
            plot(app.UIAxes, app.XData, app.YData);
            app.UIAxes.XLim = [app.XData(1), app.XData(end)];
        end

        % Callback function
        function LoadWedgesButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = uigetfile;
            
            try % Try to open the file
                
                fileID = fopen(fileName);
                % Check if fileName is a valid address.
                if fileID == -1
                    % Change the lamp color to red.
                    app.LampWedges.Color = [1 0 0];
                else 
%                     climatewedges.WedgesData = readWedges(fileID);
                    % Change the lamp color to green.
                    app.LampWedges.Color = [0 1 0];
                end
                
            catch % The file could not be opened
                
                app.LampWedges.Color = [1 0 0];
                disp('Please enter a valid file address.');
                
            end
        end

        % Callback function
        function LoadConstraintsButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = app.ConstraintsEditField.Value;
            try
                fileID = fopen(fileName);
                if fileID == -1
                    app.LampConstraints.Color = [1 0 0];
                else  
%                     climatewedges.ConstraintsData = readConstraints(fileID);
                    app.LampConstraints.Color = [0 1 0];
                end
            catch
                app.LampConstraints.Color = [1 0 0];
                disp('Please enter a valid file address.');
            end
        end

        % Callback function
        function LoadStrategyButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = app.StrategyEditField.Value;
            try
                fileID = fopen(fileName);
                if fileID == -1
                    app.LampStrategy.Color = [1 0 0];
                else
%                     climatewedges.StrategyData = readStrategy(fileID);
                    app.LampStrategy.Color = [0 1 0];
                end
            catch
                app.LampStrategy.Color = [1 0 0];
                disp('Please enter a valid file address.');
            end
        end

        % Menu selected function: LoadWedgesFileMenu
        function LoadWedgesFileMenuSelected(app, event)
            fileName = uigetfile
            fileID = fopen(fileName);
            
            %climatewedge.StrategyData = readStrategy(fileID);
        end

        % Value changed function: ElectricityStrategiesListBox
        function ElectricityStrategiesListBoxValueChanged(app, event)
            
            value = app.ElectricityStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.ElectricityStrategiesListBox.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: TransportationStrategiesListBox
        function TransportationStrategiesListBoxValueChanged(app, event)
            
            value = app.TransportationStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.TransportationStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: FuelStrategiesListBox
        function FuelStrategiesListBoxValueChanged(app, event)
            
            value = app.FuelStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.FuelStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: BiostorageStrategiesListBox
        function BiostorageStrategiesListBoxValueChanged(app, event)
           
            value = app.BiostorageStrategiesListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.BiostorageStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: StartYearTextArea
        function StartYearTextAreaValueChanged(app, event)
             %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
            
            value = app.StartYearTextArea.Value;
            
            if value > 99
                app.UIAxes.XLim = [app.XData(1), app.XData(end)];
            elseif value < 2
                app.UIAxes.XLim = [app.XData(1), app.XData(2)];
            else
                app.UIAxes.XLim = [app.XData(1), app.XData(floor(end*(value/100)))];
            end
            
        end

        % Value changed function: EndYearTextArea
        function EndYearTextAreaValueChanged(app, event)
              %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
            
            value = app.EndYearTextArea.Value;
            if value > 99
                app.UIAxes.XLim = [app.XData(1), app.XData(end)];
            elseif value < 2
                app.UIAxes.XLim = [app.XData(1), app.XData(2)];
            else
                app.UIAxes.XLim = [app.XData(1), app.XData(floor(end*(value/100)))];
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadFileMenu = uimenu(app.UIFigure);
            app.LoadFileMenu.Text = 'Load File';
            app.LoadWedgesFileMenu = uimenu(app.LoadFileMenu);
            app.LoadWedgesFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadWedgesFileMenuSelected, true);
            app.LoadWedgesFileMenu.Text = 'Load Wedges File';
            app.LoadConstraintsFileMenu = uimenu(app.LoadFileMenu);
            app.LoadConstraintsFileMenu.Text = 'Load Constraints File';
            app.LoadStrategyFileMenu = uimenu(app.LoadFileMenu);
            app.LoadStrategyFileMenu.Text = 'Load Strategy File';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StrategiesTab = uitab(app.TabGroup);
            app.StrategiesTab.Title = 'Strategies';
            app.Label = uilabel(app.StrategiesTab);
            app.Label.Text = 'Some sectors may have constraints on how many strategies can be selected at a time.';
            app.ElectricityStrategiesListBoxLabel = uilabel(app.StrategiesTab);
            app.ElectricityStrategiesListBoxLabel.Text = 'Electricity Strategies';
            app.ElectricityStrategiesListBox = uilistbox(app.StrategiesTab);
            app.ElectricityStrategiesListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.ElectricityStrategiesListBox.Multiselect = 'on';
            app.ElectricityStrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @ElectricityStrategiesListBoxValueChanged, true);
            app.ElectricityStrategiesListBox.Value = {'Strategy 1'};
            app.FuelStrategiesListBoxLabel = uilabel(app.StrategiesTab);
            app.FuelStrategiesListBoxLabel.Text = 'Fuel Strategies';
            app.FuelStrategiesListBox = uilistbox(app.StrategiesTab);
            app.FuelStrategiesListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.FuelStrategiesListBox.Multiselect = 'on';
            app.FuelStrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @FuelStrategiesListBoxValueChanged, true);
            app.FuelStrategiesListBox.Value = {'Strategy 1'};
            app.TransportationStrategiesListBoxLabel = uilabel(app.StrategiesTab);
            app.TransportationStrategiesListBoxLabel.Text = 'Transportation Strategies';
            app.TransportationStrategiesListBox = uilistbox(app.StrategiesTab);
            app.TransportationStrategiesListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.TransportationStrategiesListBox.Multiselect = 'on';
            app.TransportationStrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @TransportationStrategiesListBoxValueChanged, true);
            app.TransportationStrategiesListBox.Value = {'Strategy 1'};
            app.BiostorageStrategiesListBoxLabel = uilabel(app.StrategiesTab);
            app.BiostorageStrategiesListBoxLabel.Text = 'Biostorage Strategies';
            app.BiostorageStrategiesListBox = uilistbox(app.StrategiesTab);
            app.BiostorageStrategiesListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.BiostorageStrategiesListBox.Multiselect = 'on';
            app.BiostorageStrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @BiostorageStrategiesListBoxValueChanged, true);
            app.BiostorageStrategiesListBox.Value = {'Strategy 1'};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartYearTextAreaLabel = uilabel(app.UIFigure);
            app.StartYearTextAreaLabel.Text = 'Start Year';
            app.StartYearTextArea = uitextarea(app.UIFigure);
            app.StartYearTextArea.ValueChangedFcn = createCallbackFcn(app, @StartYearTextAreaValueChanged, true);
            app.EndYearTextAreaLabel = uilabel(app.UIFigure);
            app.EndYearTextAreaLabel.Text = 'End Year';
            app.EndYearTextArea = uitextarea(app.UIFigure);
            app.EndYearTextArea.ValueChangedFcn = createCallbackFcn(app, @EndYearTextAreaValueChanged, true);
            app.EnterastartandendLabel = uilabel(app.UIFigure);
            app.EnterastartandendLabel.Text = 'Enter a start and end';
            app.yeartoeditthegraphsLabel = uilabel(app.UIFigure);
            app.yeartoeditthegraphsLabel.Text = 'year to edit the graph''s';
            app.domainLabel = uilabel(app.UIFigure);
            app.domainLabel.Text = 'domain.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end