classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        AmplitudeSliderLabel  matlab.ui.control.Label
        AmplitudeSlider       matlab.ui.control.Slider
    end


    properties (Access = public)
        XData  %Stores XData
        YData  %Stores YData
    end


    methods (Access = private)

        % Code that executes after component creation
        function ReadData(app)
            info = tdfread('historicaldata.txt');
            app.XData = info.Year;
            app.YData = info.fossil_fuel_and_industry_GtC0x2Fyr;
            plot(app.UIAxes, app.XData, app.YData);
            app.UIAxes.XLim = [app.XData(1), app.XData(end)];
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)

            value = app.AmplitudeSlider.Value;
            if value > 99
                app.UIAxes.XLim = [app.XData(1), app.XData(end)];
            elseif value < 2
                app.UIAxes.XLim = [app.XData(1), app.XData(2)];
            else
                app.UIAxes.XLim = [app.XData(1), app.XData(floor(end*(value/100)))];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @ReadData)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end